classdef SonoScape < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SonoScapeUIFigure               matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        FolderMenu                      matlab.ui.container.Menu
        SortMenu                        matlab.ui.container.Menu
        ByDayMenu                       matlab.ui.container.Menu
        OptionsMenu                     matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        AnalyzeMenu                     matlab.ui.container.Menu
        PatternRecognizeMenu            matlab.ui.container.Menu
        GraphMenu                       matlab.ui.container.Menu
        ACIVisualizerMenu               matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        GridLayout2                     matlab.ui.container.GridLayout
        StatusLabel                     matlab.ui.control.Label
        FileListPanel                   matlab.ui.container.Panel
        ListBox                         matlab.ui.control.ListBox
        AcousticComplexityIndicesPanel  matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        TimescalesecondEditFieldLabel   matlab.ui.control.Label
        TimescalesecondEditField        matlab.ui.control.EditField
        LowEditFieldLabel_2             matlab.ui.control.Label
        LowFreqEditField                matlab.ui.control.NumericEditField
        ClumpEditFieldLabel             matlab.ui.control.Label
        ClumpEditField                  matlab.ui.control.NumericEditField
        AutoButton                      matlab.ui.control.Button
        DoButton                        matlab.ui.control.Button
        WriteButton                     matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        EnergyfilterLabel               matlab.ui.control.Label
        FreqencyfilterLabel             matlab.ui.control.Label
        HighLabel_2                     matlab.ui.control.Label
        HighFreqEditField               matlab.ui.control.NumericEditField
        ButtonGroup                     matlab.ui.container.ButtonGroup
        FarButton                       matlab.ui.control.RadioButton
        NearButton                      matlab.ui.control.RadioButton
        HighEditFieldLabel              matlab.ui.control.Label
        HighEnergyEditField             matlab.ui.control.EditField
        LowLabel                        matlab.ui.control.Label
        LowEnergyEditField              matlab.ui.control.EditField
        EcoacousticEventsButton         matlab.ui.control.Button
        ASDAnalysisButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        dataFolder % Description
        files
        
        currentIndex = 1
        currentFile
        
        signal
        Fs
        
        timescale    = 1
        energyFilter = struct('field', {'near'}, 'lowEnergy', 0, 'highEnergy', inf)
        freqFilter   = struct('lowFreq', 0, 'highFreq', inf)
        clump        = []
        
        myEco        = ecoacousticAnalysis
        myEcoEvent   = ecoacousticEvent
        
        time = struct('format', {'HHmm-ddMMYYYY'}, 'value', [])
        save = struct('ACITotal', 1, 'ACIEvenness', 1, 'ACIMatrix', 1, 'ACIIntermediate', 0)
        asciiSep = '\t'
        
        eeFailMark = 0
        eeAggregation  = struct('option', {'file'}, 'value', nan);
        
        asdAggregation = struct('option', {'file'}, 'value', nan);
        
        % state
        aciPerFile  = []
        aciAnalysis = 0
        eeAnalysis  = 0
        asdAnalysis = 0
    end
    
    methods (Access = private)
        
        function statusFeed(app, msg, varargin)
            app.StatusLabel.Text = msg;
            
            color = 'k';
            if nargin == 3
                if ~ischar(varargin{1}) && numel(varargin{1}) == 3
                    color = varargin{1};
                else
                    switch varargin{1}
                        case 'warn'
                            color = [1 .325 .286];
                        case {'error', 'r'}
                            color = 'r';
                        case 'reset'
                            color = 'k';
                    end
                end
            end
            app.StatusLabel.FontColor = color;
            
            drawnow;
        end
        
        function eeEncoding(app)
            app.EcoacousticEventsButton.Text = "Encoding ...";
            app.EcoacousticEventsButton.Icon = 'encode.gif';
            app.EcoacousticEventsButton.IconAlignment = 'left';
            drawnow;
            
            searchPath = fullfile(app.dataFolder, "Results");
            numFolders = struct2table(dir(searchPath));
            numFolders(numFolders.isdir == 0, :) = [];
            
            if height(numFolders) <= 2
                app.statusFeed("No results found", "warn");
                app.EcoacousticEventsButton.Text = "EE Analysis";
                app.EcoacousticEventsButton.Icon = '';
                app.eeFailMark = 1;
                return;
            end
            app.statusFeed("searching global acitf max ...", "reset");
            tempMaxSt = load(fullfile(searchPath, numFolders.name(3), 'cache.mat'));
            tempMax   = tempMaxSt.aciTToMax__;
            
            for iN = 4:height(numFolders)
                tempMaxSt = load(fullfile(searchPath, numFolders.name(iN), 'cache.mat'));
                
                try
                    tempMax = cellfun(@(x, y) max([x y], [], 2), tempMax, tempMaxSt.aciTToMax__, 'UniformOutput', 0);
                catch
                    app.statusFeed("folder "+numFolders.name(iN)+" seems to be analyzed using different scales!", "error");
                    app.EcoacousticEventsButton.Text = "EE Analysis";
                    app.EcoacousticEventsButton.Icon = '';
                    app.eeFailMark = 1;
                    break;
                end
            end
            if ~app.eeFailMark
                app.statusFeed("encoding ...", "reset");
                for iN = 3:height(numFolders)
                    tempMaxSt = load(fullfile(searchPath, numFolders.name(iN), 'cache.mat'));
                    app.myEcoEvent.aciTTo       = tempMaxSt.aciTTo__;
                    app.myEcoEvent.aciTToMax    = tempMax;
                    app.myEcoEvent.aciTEvenness = tempMaxSt.aciTEvenness__;
                    app.myEcoEvent.aciFEvenness = tempMaxSt.aciFEvenness__;
                    
                    app.myEcoEvent.eeCalc;
                    app.myEcoEvent.eeWrite(fullfile(searchPath, numFolders.name(iN)));
                    app.myEcoEvent.eeWriteMat(fullfile(searchPath, numFolders.name(iN)));
                end
                app.statusFeed("Done encoding", 'reset');
            end
        end
        
        function eeAna(app)
            app.statusFeed("Performing ecoacoustic analysis", 'reset');
            app.EcoacousticEventsButton.Text = "Analyzing ...";
            app.EcoacousticEventsButton.IconAlignment = 'bottom';
            
            % create cdata for wait bar
            wbar = permute(repmat(app.EcoacousticEventsButton.BackgroundColor, 15, 1, 202), [1, 3, 2]);
            wbar([1 end], :, :) = 0;
            wbar(:, [1 end], :) = 0;
            app.EcoacousticEventsButton.Icon = wbar;
            
            drawnow;
            
            searchPath = fullfile(app.dataFolder, "Results");
            numFolders = dir(searchPath);
            numFolders(~[numFolders.isdir]) = [];
            
            allcats = string(reshape(sprintf('%03d', (0:999)')', 3, [])');
            
            % id for summary file
            app.statusFeed("Generating summary files ...", 'reset');
            
            fid_f = fopen(fullfile(searchPath, "ee_fractal.txt"), 'w');
            fmt_f = ['%f' app.asciiSep '%f' app.asciiSep '%s' app.asciiSep '%f\r\n'];
            fprintf(fid_f, replace(fmt_f, 'f', 's'), 'low_energy', 'high_energy', 'filename', 'fractal_dimension');
            
            % loop through filters
            [LOW, HIGH] = ndgrid(app.energyFilter.lowEnergy, app.energyFilter.highEnergy);
            filter = [LOW(:) HIGH(:)];
            for iF = 1:size(filter, 1)
                switch app.energyFilter.field
                    case 'near'
                        nameParts = "near_" + num2str(filter(iF, 2));
                    case 'far'
                        nameParts = "far_"  + num2str(filter(iF, 1)) + '_' + num2str(filter(iF, 2));
                end
                
                fid_h = fopen(fullfile(searchPath, ...
                    "ee_entropy_" + nameParts  + '.txt'), 'w');
                fmt   = repmat(['%d' app.asciiSep], 1, numel(app.timescale));
                fmt   = ['%s' app.asciiSep fmt(1:end-length(app.asciiSep)) '\r\n'];
                fprintf(fid_h, fmt, 'filename', app.timescale);
                
                for iS = 1:numel(app.timescale)
                    xlswrite(fullfile(searchPath, ...
                        "ee_" + nameParts + '.xlsx'), ...
                    ["filename"; "'"+allcats]', ['Scale_' num2str(app.timescale(iS))], 'A1')
                end
                
                for iN = 3:numel(numFolders)
                    app.statusFeed("Analyzing "+numFolders(iN).name+" "+nameParts, 'reset');
                    tempEE = load(fullfile(searchPath, numFolders(iN).name, 'cache_ee.mat'));
                    
                    switch app.eeAggregation.option
                        case 'file'
                            freq = cellfun(@(x) eeCalc(x(:, :, iF), allcats), tempEE.ee__, 'UniformOutput', false);
                            h    = cellfun(@hCalc, freq);
                            
                            % write
                            fprintf(fid_h, replace(fmt, 'd', 'f'), numFolders(iN).name, h);
                            for iS = 1:numel(app.timescale)
                                fn = "ee_" + nameParts + '.xlsx';
                                xlswrite(fullfile(searchPath, fn), {numFolders(iN).name}, ['Scale_' num2str(app.timescale(iS))], ['A' num2str(iN-1)]);
                                xlswrite(fullfile(searchPath, fn), freq{iS}, ['Scale_' num2str(app.timescale(iS))], ['B' num2str(iN-1)]);
                            end
                    end
                    
                    % fractal dimension
                    freq  = cellfun(@(x) fdCalc(x(:, :, iF)), tempEE.ee__, 'UniformOutput', true);
                    p     = polyfit(log10(app.timescale), log10(freq), 1);
                    alpha = p(1);
                    
                    fprintf(fid_f, fmt_f, filter(iF, 1), filter(iF, 2), numFolders(iN).name, alpha);
                    
                    prog = ceil(iF/size(filter, 1) * (iN-2)/(numel(numFolders)-2)*200)+1;
                    app.EcoacousticEventsButton.Icon(2:end-1, 2:prog, 1) = .25;
                    app.EcoacousticEventsButton.Icon(2:end-1, 2:prog, 2) = .88;
                    app.EcoacousticEventsButton.Icon(2:end-1, 2:prog, 3) = .41;
                    drawnow;
                end
                
                fclose(fid_h);
            end
            fclose(fid_f);
            app.EcoacousticEventsButton.Text = "Finished";
            app.statusFeed("Done", 'reset');
            
            function freq = eeCalc(x, allcats)
                xCats = categorical(x, allcats);
                freq  = countcats(xCats);
            end
            
            function h = hCalc(x)
                x  = x./sum(x);
                x_ = x; x_(x_ == 0) = eps;
                h  = -sum(x.*log(x_));
            end
            
            function freq = fdCalc(x)
                freq = numel(unique(x));
            end
        end
        
        function resetButtonIcon(app)
            app.DoButton.Text = 'Process';
            app.DoButton.Icon = '';
            
            app.AutoButton.Text = 'AUTO';
            app.AutoButton.Icon = '';
            
            app.EcoacousticEventsButton.Text = 'Ecoacoustic Events';
            app.EcoacousticEventsButton.Icon = '';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.myEco.timescale      = app.timescale;
            app.myEcoEvent.timescale = app.timescale;
            
            if exist('scConfig.mat', 'file') == 2
                load('scConfig.mat');
                
                app.time = timeConf;
                app.save = saveConf;
                app.asciiSep = asciiSepConf;
            end
            
            app.StatusLabel.Text = "Ready";
        end

        % Menu selected function: FolderMenu
        function FolderMenuSelected(app, event)
            selpath = uigetdir(fullfile(cd, "*.wav"), "Select Folder Containing Data");
            if selpath == 0
                app.statusFeed("no folder selected", "warn");
            else
                app.dataFolder = selpath;
                allfiles  = dir(fullfile(app.dataFolder, "*.wav"));
                
                if numel(allfiles) == 0
                    app.statusFeed("no files found in the folder selected", "warn");
                    return;
                elseif numel(allfiles) == 1
                    app.files = {struct2table(allfiles).name};
                else
                    app.files = struct2table(allfiles).name;
                end
                
                app.ListBox.Items = app.files;
                app.ListBox.ItemsData = 1:length(app.files);
                app.ListBox.Value = 1;
                
                app.currentIndex  = 1;
                app.currentFile   = app.files{app.currentIndex};
                
                app.statusFeed("data loaded", "reset");
            end
            
            app.resetButtonIcon;
            
            app.aciPerFile  = zeros(length(app.files), 1);
            app.eeAnalysis  = 0;
            app.asdAnalysis = 0;
        end

        % Value changed function: TimescalesecondEditField
        function TimescalesecondEditFieldValueChanged(app, event)
            value = app.TimescalesecondEditField.Value;
            try
                app.timescale = eval(value);
                app.myEco.timescale      = app.timescale;
                app.myEcoEvent.timescale = app.timescale;
                app.statusFeed("Time scale defined", "reset");
            catch
                app.statusFeed("Invalid expression used for time scale!", "error");
            end
        end

        % Value changed function: LowEnergyEditField
        function LowEnergyEditFieldValueChanged(app, event)
            value = app.LowEnergyEditField.Value;
            try
                app.energyFilter.lowEnergy = eval(value);
                app.myEcoEvent.lowEnergy   = eval(value);
                app.statusFeed("Filter applied", "reset");
            catch
                app.statusFeed("Invalid expression used for low energy filter!", "error");
            end
        end

        % Value changed function: LowFreqEditField
        function LowFreqEditFieldValueChanged(app, event)
            value = app.LowFreqEditField.Value;
            app.freqFilter.lowFreq = value;
            app.statusFeed("Filter applied", "reset");
        end

        % Value changed function: ClumpEditField
        function ClumpEditFieldValueChanged(app, event)
            value = app.ClumpEditField.Value;
            if value == 0
                app.clump = [];
            else
                app.clump = value;
            end
            app.statusFeed("Clump applied", "reset");
        end

        % Button pushed function: DoButton
        function DoButtonPushed(app, event)
            app.DoButton.Text = 'Processing ...';
            app.DoButton.IconAlignment = 'bottom';
            
            % create cdata for wait bar
            wbar = permute(repmat(app.DoButton.BackgroundColor, 15, 1, 202), [1, 3, 2]);
            wbar([1 end], :, :) = 0;
            wbar(:, [1 end], :) = 0;
            app.DoButton.Icon = wbar;
            
            % Process
            % Parse data
            if isempty(app.files)
                return;
            end
            
            if app.currentIndex > length(app.files)
                app.statusFeed("Already hit the last file; job's done", "reset");
                return;
            end
            
            [app.signal, app.Fs] = audioread(fullfile(app.dataFolder, app.currentFile));
            drawnow;
            
            % progression 10%
            app.statusFeed("Data loaded", "reset");
            app.DoButton.Icon(2:end-1, 2:21, 1) = .25;
            app.DoButton.Icon(2:end-1, 2:21, 2) = .88;
            app.DoButton.Icon(2:end-1, 2:21, 3) = .41;
            drawnow;
            
            app.myEco.ts = app.signal;
            app.myEco.fs = app.Fs;
            
            app.myEco.window       = hann(1024);
            app.myEco.windowLength = 1024;
            
            app.myEco.acousticComplexity = struct(...
                'energyFilter',   app.energyFilter, ...
                'freqFilter',     app.freqFilter, ...
                'clumping',       app.clump);
            
            % progression 40%
            app.statusFeed("Wait ...", "reset");
            drawnow;
            tsRescale = app.myEco.rescale;
            app.myEco.stft(tsRescale);
            app.statusFeed("STFT done", "reset");
            app.DoButton.Icon(2:end-1, 22:81, 1) = .25;
            app.DoButton.Icon(2:end-1, 22:81, 2) = .88;
            app.DoButton.Icon(2:end-1, 22:81, 3) = .41;
            drawnow;
            
            % progression 60%
            app.myEco.aci;
            app.statusFeed("ACI done", "reset");
            app.DoButton.Icon(2:end-1, 82:121, 1) = .25;
            app.DoButton.Icon(2:end-1, 82:121, 2) = .88;
            app.DoButton.Icon(2:end-1, 82:121, 3) = .41;
            drawnow;
            
            % progression 100%
            app.myEco.aciEvenness;
            app.myEco.aciTotal
            app.statusFeed("ACI evenness done", "reset");
            app.DoButton.Icon(2:end-1, 122:end-1, 1) = .25;
            app.DoButton.Icon(2:end-1, 122:end-1, 2) = .88;
            app.DoButton.Icon(2:end-1, 122:end-1, 3) = .41;
            drawnow;
            
            % temp max
            app.myEco.aciTotalMax;
            
            % After process
            app.DoButton.Text = 'Done';
            drawnow;
            
            % analysis state
            app.aciPerFile(app.currentIndex) = 1;
            if all(app.aciPerFile)
                app.aciAnalysis = 1;
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.currentIndex < length(app.files)
                app.currentIndex  = app.currentIndex + 1;
                app.ListBox.Value = app.currentIndex;
                app.currentFile   = app.files{app.currentIndex};
                
                app.DoButton.Icon = '';
                app.DoButton.Text = 'Process';
                app.statusFeed("File selected", "reset");
            else
                app.currentIndex = length(app.files) + 1;
                app.statusFeed("No more files", "warn");
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.currentIndex = value;
            app.currentFile  = app.files{app.currentIndex};
            app.statusFeed("File selected", "reset");
            
            app.DoButton.Icon = '';
            app.DoButton.Text = 'Process';
            
            % parse prior runs
            if exist(fullfile(app.dataFolder, 'Results', app.currentFile(1:end-4), 'cache.mat'), 'file') == 2
                app.aciPerFile(app.currentIndex) = 1;
            end
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)
            if isempty(app.myEco.aciFTo)
                app.statusFeed("Nothing to save", 'warn');
                return;
            end
            
            app.statusFeed("Saving results ...", "reset");
            drawnow;
            
            savePt = fullfile(app.dataFolder, "Results", app.currentFile(1:end-4));
            if ~(exist(savePt, 'dir') == 7)
                mkdir(savePt);
            end
            
            app.myEco.aciWriteSeperate(savePt, app.asciiSep);
            app.myEco.aciWriteMax(savePt, app.asciiSep);
            app.myEco.aciWriteMat(savePt);
            app.myEco.aciWriteACIMatrix(savePt, app.asciiSep);
            
            % save intermediate
            if app.save.ACIIntermediate
                app.myEco.aciWriteIntermediate(savePt, app.asciiSep);
            end
            
            app.statusFeed("Results saved", "reset");
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            app.AutoButton.Icon = 'spinner.gif';
            app.AutoButton.IconAlignment = 'bottom';
            drawnow;
            
            % back up Results
            oldRes = fullfile(app.dataFolder, "Results");
            bacRes = fullfile(app.dataFolder, "Results_"+datestr(now, 'yyyymmddHHMMss'));
            if exist(oldRes, "dir") == 7
                app.statusFeed("Backing up previous analyses", "warn");
                movefile(oldRes, bacRes);
            end
            
            for iF = app.currentIndex:length(app.files)
                app.DoButtonPushed;
                app.WriteButtonPushed;
                app.NextButtonPushed;
            end
            
            app.statusFeed("Done", "reset");
            app.AutoButton.Icon = '';
            
            app.aciAnalysis = 1;
        end

        % Button pushed function: EcoacousticEventsButton
        function EcoacousticEventsButtonPushed(app, event)
            app.eeFailMark = 0;
            app.eeEncoding;
            if app.eeFailMark
                return;
            end
            app.eeAna;
            
            app.eeAnalysis = 1;
        end

        % Menu selected function: OptionsMenu
        function OptionsMenuSelected(app, event)
            called = optionWindow(app.time, app.save, app.asciiSep);
            app.statusFeed('Waiting for configuration ...');
            uiwait(called.WizardFigure)
            if called.imported
                app.statusFeed("Configuration changed");
                app.time = called.time;
                app.save = called.save;
                app.asciiSep = called.asciiSep;
            else
                app.statusFeed('Nothing changed.');
            end
            
            called.delete;
        end

        % Button pushed function: ASDAnalysisButton
        function ASDAnalysisButtonPushed(app, event)
            app.statusFeed("Performing acoustic signiture dissimilarity analysis", 'reset');
            app.ASDAnalysisButton.Text = "Analyzing ...";
            app.ASDAnalysisButton.IconAlignment = 'bottom';
            
            % create cdata for wait bar
            wbar = permute(repmat(app.ASDAnalysisButton.BackgroundColor, 15, 1, 202), [1, 3, 2]);
            wbar([1 end], :, :) = 0;
            wbar(:, [1 end], :) = 0;
            app.ASDAnalysisButton.Icon = wbar;
            
            drawnow;
            
            searchPath = fullfile(app.dataFolder, "Results");
            numFolders = dir(searchPath);
            numFolders(~[numFolders.isdir]) = [];
            
            % id for summary file
            app.statusFeed("Generating summary files ...", 'reset');
            
            fid_f = fopen(fullfile(searchPath, "asd_fractal.txt"), 'w');
            fmt_f = ['%f' app.asciiSep '%f' app.asciiSep '%s' app.asciiSep '%f\r\n'];
            fprintf(fid_f, replace(fmt_f, 'f', 's'), 'low_energy', 'high_energy', 'filename', 'fractal_dimension');
            
            % loop through filters
            [LOW, HIGH] = ndgrid(app.energyFilter.lowEnergy, app.energyFilter.highEnergy);
            filter = [LOW(:) HIGH(:)];
            for iF = 1:size(filter, 1)
                switch app.energyFilter.field
                    case 'near'
                        nameParts = "near_" + num2str(filter(iF, 2));
                    case 'far'
                        nameParts = "far_"  + num2str(filter(iF, 1)) + '_' + num2str(filter(iF, 2));
                end
                
                fid_h = fopen(fullfile(searchPath, 'asd_'+nameParts+'.txt'), 'w');
                fmt   = repmat(['%d' app.asciiSep], 1, numel(app.timescale));
                fmt   = ['%s' app.asciiSep fmt(1:end-length(app.asciiSep)) '\r\n'];
                fprintf(fid_h, fmt, 'filename', app.timescale);
                
                for iN = 3:numel(numFolders)
                    app.statusFeed("Analyzing "+numFolders(iN).name+" "+nameParts, 'reset');
                    
                    temp = load(fullfile(searchPath, numFolders(iN).name, 'cache.mat'));
                    
                    asd  = nan(size(app.timescale));
                    for iS = 1:numel(app.timescale)
                        acitf = temp.aciF__{iS}(:, :, iF);
                        
                        switch app.asdAggregation.option
                            case 'file'
                                d = pdist(acitf', @dchord);
                                asd(iS) = sum(d)./length(d);
                        end
                    end
                    
                    % write
                    fprintf(fid_h, replace(fmt, 'd', 'f'), numFolders(iN).name, asd);
                    
                    % fractal dimension
                    p     = polyfit(log10(app.timescale), log10(asd), 1);
                    alpha = p(1);
                    
                    fprintf(fid_f, fmt_f, filter(iF, 1), filter(iF, 2), numFolders(iN).name, alpha);
                    
                    prog = ceil(iF/size(filter, 1) * (iN-2)/(numel(numFolders)-2)*200)+1;
                    app.ASDAnalysisButton.Icon(2:end-1, 2:prog, 1) = .25;
                    app.ASDAnalysisButton.Icon(2:end-1, 2:prog, 2) = .88;
                    app.ASDAnalysisButton.Icon(2:end-1, 2:prog, 3) = .41;
                    drawnow;
                end
            end
            app.ASDAnalysisButton.Text = "Finished";
            app.statusFeed("Done", 'reset');
            
            fclose(fid_h);
            
            app.asdAnalysis = 1;
            
            function d = dchord(XI, XJ)
                d = sqrt(sum((XI./norm(XI) - XJ./repmat(vecnorm(XJ, 2, 2), 1, size(XJ, 2))).^2, 2));
            end
        end

        % Menu selected function: ACIVisualizerMenu
        function ACIVisualizerMenuSelected(app, event)
            if app.aciAnalysis && app.eeAnalysis && app.asdAnalysis
                aciResultControl(app.timescale, ...
                    app.energyFilter.field, app.energyFilter.lowEnergy, app.energyFilter.highEnergy, ...
                    app.currentFile(1:end-4), fullfile(app.dataFolder, "Results"));
            end
        end

        % Menu selected function: PatternRecognizeMenu
        function PatternRecognizeMenuSelected(app, event)
            if app.aciPerFile(app.currentIndex)
                patternRecognize(app.currentFile, app.dataFolder);
            end
        end

        % Menu selected function: ByDayMenu
        function ByDayMenuSelected(app, event)
            selpath = uigetdir(fullfile(cd, "*.wav"), "Select Folder Containing Data");
            if selpath == 0
                app.statusFeed("no folder selected", "warn");
            else
                allfiles = dir(fullfile(selpath, "*.wav"));
                
                if numel(allfiles) == 0
                    app.statusFeed("no files found in the folder selected", "warn");
                    return;
                elseif numel(allfiles) == 1
                    fileNames = {struct2table(allfiles).name};
                else
                    fileNames = struct2table(allfiles).name;
                end
                
                app.statusFeed("moving "+num2str(numel(fileNames))+" files", "reset");
                drawnow;
                
                % move files
                failed = 0;
                for iF = 1:numel(fileNames)
                    [datetimestr, format] = parsetime(fileNames{iF}, app.time.format);
                    
                    try
                        fileDateTime = datetime(datetimestr, "InputFormat", format);
                        
                        folderName   = [num2str(fileDateTime.Year) sprintf('%02d', fileDateTime.Month) sprintf('%02d', fileDateTime.Day)];
                        if ~(exist(fullfile(selpath, folderName), 'dir') == 7)
                            mkdir(fullfile(selpath, folderName));
                        end
                        movefile(fullfile(selpath, fileNames{iF}), fullfile(selpath, folderName));
                    catch
                        failed = failed + 1;
                    end
                end
                
                app.statusFeed("moved "+sprintf('%d', numel(fileNames)-failed)+" out of "+sprintf('%d', numel(fileNames))+" files", "reset");
            end
            
            app.resetButtonIcon;
            
            function [datetimestr, format] = parsetime(str, format)
                ind = strfind(format, '*');
                
                if numel(ind) == 1
                    if ind == 1
                        datetimestr = str(end-length(format)+1+1:end);
                    else
                        datetimestr = str(1:ind-1);
                    end
                else
                    datetimestr = str(setxor(1:length(str), ind));
                end
                
                format      = format(setxor(1:length(format), ind));
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'Near'
                    app.LowEnergyEditField.Enable  = 'off';
                    app.LowLabel.Enable            = 'off';
                    app.energyFilter.field         = 'near';
                    app.myEcoEvent.field           = 'near';
                case 'Far'
                    app.LowEnergyEditField.Enable  = 'on';
                    app.LowLabel.Enable            = 'on';
                    app.energyFilter.field         = 'far';
                    app.myEcoEvent.field           = 'far';
            end
            
            app.statusFeed("Field applied", "reset");
        end

        % Value changed function: HighEnergyEditField
        function HighEnergyEditFieldValueChanged(app, event)
            value = app.HighEnergyEditField.Value;
            try
                app.energyFilter.highEnergy = eval(value);
                app.myEcoEvent.highEnergy   = eval(value);
                app.statusFeed("Filter applied", "reset");
            catch
                app.statusFeed("Invalid expression used for high energy filter!", "error");
            end
        end

        % Value changed function: HighFreqEditField
        function HighFreqEditFieldValueChanged(app, event)
            value = app.HighFreqEditField.Value;
            if value ~= 0
                app.freqFilter.highFreq = value;
            else
                app.freqFilter.highFreq = inf;
            end
            app.statusFeed("Filter applied", "reset");
        end

        % Callback function: ExitMenu, SonoScapeUIFigure
        function SonoScapeUIFigureCloseRequest(app, event)
            delete(app);
            
            % collect all child
            allShow = allchild(0);
            delete(allShow);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            about;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SonoScapeUIFigure = uifigure('Visible', 'off');
            app.SonoScapeUIFigure.Name = 'SonoScape';
            app.SonoScapeUIFigure.CloseRequestFcn = createCallbackFcn(app, @SonoScapeUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.SonoScapeUIFigure);
            app.FileMenu.Text = '&File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.FolderMenu = uimenu(app.OpenMenu);
            app.FolderMenu.MenuSelectedFcn = createCallbackFcn(app, @FolderMenuSelected, true);
            app.FolderMenu.Text = 'Folder';
            app.SortMenu = uimenu(app.FileMenu);
            app.SortMenu.Separator = 'on';
            app.SortMenu.Text = 'Sort';
            app.ByDayMenu = uimenu(app.SortMenu);
            app.ByDayMenu.MenuSelectedFcn = createCallbackFcn(app, @ByDayMenuSelected, true);
            app.ByDayMenu.Text = 'By Day';
            app.OptionsMenu = uimenu(app.FileMenu);
            app.OptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @OptionsMenuSelected, true);
            app.OptionsMenu.Separator = 'on';
            app.OptionsMenu.Accelerator = 'O';
            app.OptionsMenu.Text = 'Options';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @SonoScapeUIFigureCloseRequest, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Accelerator = 'Q';
            app.ExitMenu.Text = 'Exit';
            app.AnalyzeMenu = uimenu(app.SonoScapeUIFigure);
            app.AnalyzeMenu.Text = '&Analyze';
            app.PatternRecognizeMenu = uimenu(app.AnalyzeMenu);
            app.PatternRecognizeMenu.MenuSelectedFcn = createCallbackFcn(app, @PatternRecognizeMenuSelected, true);
            app.PatternRecognizeMenu.Accelerator = 'R';
            app.PatternRecognizeMenu.Text = 'Pattern Recognize';
            app.GraphMenu = uimenu(app.SonoScapeUIFigure);
            app.GraphMenu.Text = '&Graph';
            app.ACIVisualizerMenu = uimenu(app.GraphMenu);
            app.ACIVisualizerMenu.MenuSelectedFcn = createCallbackFcn(app, @ACIVisualizerMenuSelected, true);
            app.ACIVisualizerMenu.Accelerator = 'L';
            app.ACIVisualizerMenu.Text = 'ACI Visualizer';
            app.HelpMenu = uimenu(app.SonoScapeUIFigure);
            app.HelpMenu.Text = '&Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Accelerator = 'A';
            app.AboutMenu.Text = 'About';
            app.GridLayout2 = uigridlayout(app.SonoScapeUIFigure);
            app.StatusLabel = uilabel(app.GridLayout2);
            app.StatusLabel.Text = 'Status';
            app.FileListPanel = uipanel(app.GridLayout2);
            app.FileListPanel.Title = 'File List';
            app.ListBox = uilistbox(app.FileListPanel);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.AcousticComplexityIndicesPanel = uipanel(app.GridLayout2);
            app.AcousticComplexityIndicesPanel.Title = 'Acoustic Complexity Indices';
            app.GridLayout = uigridlayout(app.AcousticComplexityIndicesPanel);
            app.TimescalesecondEditFieldLabel = uilabel(app.GridLayout);
            app.TimescalesecondEditFieldLabel.Text = 'Time scale (second):';
            app.TimescalesecondEditField = uieditfield(app.GridLayout, 'text');
            app.TimescalesecondEditField.ValueChangedFcn = createCallbackFcn(app, @TimescalesecondEditFieldValueChanged, true);
            app.TimescalesecondEditField.Tooltip = {'Syntaxed expression allowed. E.g., 1:5, [1:5, 10:10:100]'};
            app.TimescalesecondEditField.Value = '1';
            app.LowEditFieldLabel_2 = uilabel(app.GridLayout);
            app.LowEditFieldLabel_2.Text = 'Low:';
            app.LowFreqEditField = uieditfield(app.GridLayout, 'numeric');
            app.LowFreqEditField.Limits = [0 Inf];
            app.LowFreqEditField.ValueChangedFcn = createCallbackFcn(app, @LowFreqEditFieldValueChanged, true);
            app.ClumpEditFieldLabel = uilabel(app.GridLayout);
            app.ClumpEditFieldLabel.Text = 'Clump:';
            app.ClumpEditField = uieditfield(app.GridLayout, 'numeric');
            app.ClumpEditField.Limits = [0 Inf];
            app.ClumpEditField.ValueChangedFcn = createCallbackFcn(app, @ClumpEditFieldValueChanged, true);
            app.AutoButton = uibutton(app.GridLayout, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'AUTO';
            app.DoButton = uibutton(app.GridLayout, 'push');
            app.DoButton.ButtonPushedFcn = createCallbackFcn(app, @DoButtonPushed, true);
            app.DoButton.Text = 'Process';
            app.WriteButton = uibutton(app.GridLayout, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Icon = 'write.png';
            app.WriteButton.Text = 'Save';
            app.NextButton = uibutton(app.GridLayout, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Icon = 'next.gif';
            app.NextButton.Text = 'Next';
            app.EnergyfilterLabel = uilabel(app.GridLayout);
            app.EnergyfilterLabel.Text = 'Energy filter';
            app.FreqencyfilterLabel = uilabel(app.GridLayout);
            app.FreqencyfilterLabel.Text = 'Freqency filter';
            app.HighLabel_2 = uilabel(app.GridLayout);
            app.HighLabel_2.Text = 'High:';
            app.HighFreqEditField = uieditfield(app.GridLayout, 'numeric');
            app.HighFreqEditField.Limits = [0 Inf];
            app.HighFreqEditField.ValueChangedFcn = createCallbackFcn(app, @HighFreqEditFieldValueChanged, true);
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.FarButton = uiradiobutton(app.ButtonGroup);
            app.FarButton.Text = 'Far';
            app.NearButton = uiradiobutton(app.ButtonGroup);
            app.NearButton.Text = 'Near';
            app.NearButton.Value = true;
            app.HighEditFieldLabel = uilabel(app.GridLayout);
            app.HighEditFieldLabel.Text = 'High:';
            app.HighEnergyEditField = uieditfield(app.GridLayout, 'text');
            app.HighEnergyEditField.ValueChangedFcn = createCallbackFcn(app, @HighEnergyEditFieldValueChanged, true);
            app.HighEnergyEditField.Tooltip = {'Syntaxed expression allowed. E.g., 1:5, [1:5, 10:10:100]'};
            app.HighEnergyEditField.Value = '0';
            app.LowLabel = uilabel(app.GridLayout);
            app.LowLabel.Enable = 'off';
            app.LowLabel.Text = 'Low:';
            app.LowEnergyEditField = uieditfield(app.GridLayout, 'text');
            app.LowEnergyEditField.ValueChangedFcn = createCallbackFcn(app, @LowEnergyEditFieldValueChanged, true);
            app.LowEnergyEditField.Enable = 'off';
            app.LowEnergyEditField.Tooltip = {'Syntaxed expression allowed. E.g., 1:5, [1:5, 10:10:100]'};
            app.LowEnergyEditField.Value = '0';
            app.EcoacousticEventsButton = uibutton(app.GridLayout2, 'push');
            app.EcoacousticEventsButton.ButtonPushedFcn = createCallbackFcn(app, @EcoacousticEventsButtonPushed, true);
            app.EcoacousticEventsButton.Text = 'Ecoacoustic Events';
            app.ASDAnalysisButton = uibutton(app.GridLayout2, 'push');
            app.ASDAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ASDAnalysisButtonPushed, true);
            app.ASDAnalysisButton.Text = 'ASD Analysis';
            % Show the figure after all components are created
            app.SonoScapeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SonoScape

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SonoScapeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SonoScapeUIFigure)
        end
    end
end