classdef aciResultControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ACIVisualizerControlPanelUIFigure  matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        ConfirmButton          matlab.ui.control.Button
        CloseButton            matlab.ui.control.Button
        SelectparametercombinationstovisualizetheresultsLabel  matlab.ui.control.Label
        TimescaleButtonGroup   matlab.ui.container.ButtonGroup
        LowenergyButtonGroup   matlab.ui.container.ButtonGroup
        HighenergyButtonGroup  matlab.ui.container.ButtonGroup
    end

    
    properties (Access = public)
        currentScale
        currentLowEnergy
        currentHighEnergy
    end
    
    properties (Access = private)
        timescale
        currentScaleIndex = 1
        
        field
        lowEnergy
        highEnergy
        
        filename
        readpath
        resultSheet
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, timescale, field, lowEnergy, highEnergy, filename, readpath)
            app.timescale  = timescale;
            app.field      = field;
            app.lowEnergy  = lowEnergy;
            app.highEnergy = highEnergy;
            app.filename   = filename;
            app.readpath   = readpath;
            
            app.currentScale      = timescale(1);
            app.currentLowEnergy  = lowEnergy(1);
            app.currentHighEnergy = highEnergy(1);
            
            rbGenerator(app, "TimescaleButtonGroup",  timescale,  " second");
            rbGenerator(app, "LowenergyButtonGroup",  lowEnergy,  "");
            rbGenerator(app, "HighenergyButtonGroup", highEnergy, "");
            
            function rbGenerator(app, bgHandle, para, unit)
                % generate radiobutton based on para
                def = uiradiobutton(app.(bgHandle), 'Value', 0, ...
                    "Text", num2str(para(end))+unit, ...
                    'Tag', bgHandle+"_"+num2str(length(para)));
                
                int        = 30;
                maxButtons = ...
                    floor(app.(bgHandle).Position(4) / int);
                
                if numel(para) < maxButtons
                    adjBottom = app.(bgHandle).Position(4) ...
                        - (numel(para)-1)*int;
                    def.Position = [def.Position(1) adjBottom def.Position([3 4])];
                end
                
                if numel(para) > 1
                    for iT = numel(para)-1:-1:2
                        uiradiobutton(app.(bgHandle), 'Value', 0, ...
                            "Text", num2str(para(iT))+unit, ...
                            "Position", def.Position+[0 int*(numel(para)-iT) 0 0], ...
                            'Tag', bgHandle+"_"+num2str(iT));
                    end
                    uiradiobutton(app.(bgHandle), 'Value', 1, ...
                        "Text", num2str(para(1))+unit, ...
                        "Position", def.Position+[0 int*(numel(para)-1) 0 0], ...
                        'Tag', bgHandle+"_"+num2str(1));
                end
            end
        end

        % Selection changed function: TimescaleButtonGroup
        function TimescaleButtonGroupSelectionChanged(app, event)
            selectedButton = app.TimescaleButtonGroup.SelectedObject;
            
            temp = strsplit(selectedButton.Tag, '_');
            app.currentScaleIndex = str2double(temp(end));
            app.currentScale      = app.timescale(str2double(temp(end)));
        end

        % Selection changed function: LowenergyButtonGroup
        function LowenergyButtonGroupSelectionChanged(app, event)
            selectedButton = app.LowenergyButtonGroup.SelectedObject;
            
            temp = strsplit(selectedButton.Tag, '_');
            app.currentLowEnergy = app.lowEnergy(str2double(temp(end)));
        end

        % Selection changed function: HighenergyButtonGroup
        function HighenergyButtonGroupSelectionChanged(app, event)
            selectedButton = app.HighenergyButtonGroup.SelectedObject;
            
            temp = strsplit(selectedButton.Tag, '_');
            app.currentHighEnergy = app.highEnergy(str2double(temp(end)));
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            [LOW, HIGH] = ndgrid(app.lowEnergy, app.highEnergy);
            filter      = [LOW(:) HIGH(:)];
            numFilters  = 1:size(filter, 1);
            currentFilterIndex = numFilters(all(filter == [app.currentLowEnergy, app.currentHighEnergy], 2));
            
            app.resultSheet = aciResultSheet(app.timescale, ...
                app.currentScaleIndex, ...
                app.field, ...
                app.currentLowEnergy, ...
                app.currentHighEnergy, ...
                currentFilterIndex, ...
                app.filename, app.readpath);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.ACIVisualizerControlPanelUIFigureCloseRequest;
        end

        % Close request function: ACIVisualizerControlPanelUIFigure
        function ACIVisualizerControlPanelUIFigureCloseRequest(app, event)
            % collect all sub windows
            allShow = allchild(0);
            visHand = contains({allShow.Name}, 'Visualizing ACI');
            delete(allShow(visHand));
            app.resultSheet = [];
            
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ACIVisualizerControlPanelUIFigure = uifigure('Visible', 'off');
            app.ACIVisualizerControlPanelUIFigure.Name = 'ACI Visualizer Control Panel';
            app.ACIVisualizerControlPanelUIFigure.CloseRequestFcn = createCallbackFcn(app, @ACIVisualizerControlPanelUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.ACIVisualizerControlPanelUIFigure);
            app.ConfirmButton = uibutton(app.GridLayout, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SelectparametercombinationstovisualizetheresultsLabel = uilabel(app.GridLayout);
            app.SelectparametercombinationstovisualizetheresultsLabel.Text = 'Select parameter combinations to visualize the results';
            app.TimescaleButtonGroup = uibuttongroup(app.GridLayout);
            app.TimescaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TimescaleButtonGroupSelectionChanged, true);
            app.TimescaleButtonGroup.Title = 'Time scale';
            app.LowenergyButtonGroup = uibuttongroup(app.GridLayout);
            app.LowenergyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LowenergyButtonGroupSelectionChanged, true);
            app.LowenergyButtonGroup.Title = 'Low energy';
            app.HighenergyButtonGroup = uibuttongroup(app.GridLayout);
            app.HighenergyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @HighenergyButtonGroupSelectionChanged, true);
            app.HighenergyButtonGroup.Title = 'High energy';
            % Show the figure after all components are created
            app.ACIVisualizerControlPanelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aciResultControl(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ACIVisualizerControlPanelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ACIVisualizerControlPanelUIFigure)
        end
    end
end