classdef aciResultSheet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ACIResultSheetFigure  matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        ExportMenu            matlab.ui.container.Menu
        FigureMenu            matlab.ui.container.Menu
        GridLayout            matlab.ui.container.GridLayout
        ACIPanel              matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        tfAx                  matlab.ui.control.UIAxes
        ftAx                  matlab.ui.control.UIAxes
        ftEvenAx              matlab.ui.control.UIAxes
        tfEvenAx              matlab.ui.control.UIAxes
        Panel                 matlab.ui.container.Panel
        GridLayout3           matlab.ui.container.GridLayout
        entropyAx             matlab.ui.control.UIAxes
        asdAx                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        aciRes
        entRes
        asdRes
        
        timescale
        scaleIndex
        
        filterIndex
        
        readpt
        filename
    end
    
    methods (Access = private)
        
        function plotPerScale(app)
            % tf
            aciTf = app.aciRes.aciF__{app.scaleIndex}(:, :, app.filterIndex);
            waterfall(app.tfAx, 1:size(aciTf, 1), 1:size(aciTf, 2), aciTf');
            set(app.tfAx.Children, 'FaceColor', 'none', 'LineWidth', 1);
            colormap(app.tfAx, 'jet');
            grid(app.tfAx, 'minor');
            
            app.tfAx.View = [-15, 60];
            app.tfAx.YLim =  [0 size(aciTf, 2)+1];
            
            zlabel(app.tfAx, 'Tf');
            
            % ft
            aciFt = app.aciRes.aciT__{app.scaleIndex}(:, :, app.filterIndex);
            waterfall(app.ftAx, 1:size(aciFt, 1), 1:size(aciFt, 2), aciFt');
            set(app.ftAx.Children, 'FaceColor', 'none', 'LineWidth', 1);
            colormap(app.ftAx, 'jet');
            grid(app.ftAx, 'minor');
            
            app.ftAx.View = [-15, 60];
            app.ftAx.YLim =  [0 size(aciFt, 2)+1];
            
            zlabel(app.ftAx, 'Ft');
            
            % tf evenness
            aciTfEvenness = app.aciRes.aciFEvenness__{app.scaleIndex}(:, :, app.filterIndex);
            plot(app.tfEvenAx, 1:length(aciTfEvenness), aciTfEvenness, ...
                'LineWidth', 2);
            set(app.tfEvenAx, 'XLim', [-.5 length(aciTfEvenness)+.5]);
            
            % ft evenness
            aciFtEvenness = app.aciRes.aciTEvenness__{app.scaleIndex}(:, :, app.filterIndex);
            plot(app.ftEvenAx, 1:length(aciFtEvenness), aciFtEvenness, ...
                'LineWidth', 2);
            set(app.ftEvenAx, 'XLim', [-.5 length(aciFtEvenness)+.5]);
            
            % show title
            app.ACIPanel.Title = "ACI scale: " ...
                + num2str(app.timescale(app.scaleIndex)) + " second";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, timescale, scaleIndex, field, lowEnergy, highEnergy, filterIndex, filename, readpt)
            app.timescale   = timescale;
            app.scaleIndex  = scaleIndex;
            app.filterIndex = filterIndex;
            app.readpt      = readpt;
            app.filename    = filename;
            
            app.ACIResultSheetFigure.Visible = 'off';
            
            % load data
            app.aciRes   = load(fullfile(readpt, filename, 'cache.mat'));
            
            switch field
                case 'near'
                    nameParts = "_near_" + num2str(highEnergy);
                case 'far'
                    nameParts = "_far_"  + num2str(lowEnergy) + "_" + num2str(highEnergy);
            end
            
            app.ACIResultSheetFigure.Name = ...
                app.ACIResultSheetFigure.Name + string(filename) + nameParts;
            
            app.entRes   = readtable(fullfile(readpt, 'ee_entropy' + nameParts + '.txt'), ...
                "ReadVariableNames", 1);
            app.asdRes   = readtable(fullfile(readpt, 'ssd' + nameParts + '.txt'), ...
                "ReadVariableNames", 1);
            
            % plot default: entropy and asd
            curEnt = app.entRes{strcmp(app.entRes.(1), filename), 2:end};
            curASD = app.asdRes{strcmp(app.entRes.(1), filename), 2:end};
            
            hEnt = plot(app.entropyAx, timescale, curEnt, 'LineWidth', 2);
            hASD = plot(app.asdAx,     timescale, curASD, 'LineWidth', 2);
            
            if numel(timescale) == 1
                set([hEnt hASD], 'Marker', 'o', 'MarkerSize', 10);
            else
                set([hEnt hASD], 'Marker', 'none');
            end
            
            set([app.entropyAx app.asdAx], ...
                'XLim', [min(timescale)-.5 max(timescale)+.5]);
            
            % plot default: per scale results
            app.plotPerScale;
            
            % maximize window
            drawnow;
            app.ACIResultSheetFigure.WindowState = 'maximized';
            
            % invisible before finishing plotting
            pause(5);
            app.ACIResultSheetFigure.Visible     = 'on';
        end

        % Menu selected function: FigureMenu
        function FigureMenuSelected(app, event)
            filter = {'*.pdf'; '*.jpg'; '*.png'; '*.tif'};
            [figFilename, figFilepath] = uiputfile(filter, 'Save', fullfile(app.readpt, app.filename));
            if ischar(figFilename)
                exportapp(app.ACIResultSheetFigure, fullfile(figFilepath, figFilename));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ACIResultSheetFigure = uifigure('Visible', 'off');
            app.ACIResultSheetFigure.Name = 'Visualizing ACI: ';
            app.FileMenu = uimenu(app.ACIResultSheetFigure);
            app.FileMenu.Text = '&File';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Accelerator = 'E';
            app.ExportMenu.Text = 'Export';
            app.FigureMenu = uimenu(app.ExportMenu);
            app.FigureMenu.MenuSelectedFcn = createCallbackFcn(app, @FigureMenuSelected, true);
            app.FigureMenu.Text = 'Figure';
            app.GridLayout = uigridlayout(app.ACIResultSheetFigure);
            app.ACIPanel = uipanel(app.GridLayout);
            app.ACIPanel.Title = 'ACI';
            app.GridLayout2 = uigridlayout(app.ACIPanel);
            app.tfAx = uiaxes(app.GridLayout2);
            xlabel(app.tfAx, 'freq line')
            ylabel(app.tfAx, 'time step')
            app.ftAx = uiaxes(app.GridLayout2);
            xlabel(app.ftAx, 'fft frame')
            ylabel(app.ftAx, 'time step')
            app.ftEvenAx = uiaxes(app.GridLayout2);
            xlabel(app.ftEvenAx, 'time step')
            ylabel(app.ftEvenAx, 'Ft_{evenness}')
            app.tfEvenAx = uiaxes(app.GridLayout2);
            xlabel(app.tfEvenAx, 'time step')
            ylabel(app.tfEvenAx, 'Tf_{evenness}')
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.entropyAx = uiaxes(app.GridLayout3);
            xlabel(app.entropyAx, 'time scale')
            ylabel(app.entropyAx, 'entropy')
            app.asdAx = uiaxes(app.GridLayout3);
            xlabel(app.asdAx, 'time scale')
            ylabel(app.asdAx, 'SSD')
            % Show the figure after all components are created
            app.ACIResultSheetFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aciResultSheet(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ACIResultSheetFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ACIResultSheetFigure)
        end
    end
end